classdef soundannotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SoundAnnotatorMenu       matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        HowToUseMenu             matlab.ui.container.Menu
        AboutMenu                matlab.ui.container.Menu
        ResetMenu                matlab.ui.container.Menu
        ControlsPanel            matlab.ui.container.Panel
        VolumeSliderLabel        matlab.ui.control.Label
        VolumeSlider             matlab.ui.control.Slider
        EditPanel                matlab.ui.container.Panel
        StartSpinnerLabel        matlab.ui.control.Label
        StartSpinner             matlab.ui.control.Spinner
        EndSpinnerLabel          matlab.ui.control.Label
        EndSpinner               matlab.ui.control.Spinner
        CutButton                matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        PauseButton              matlab.ui.control.Button
        ResumeButton             matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        Track2Panel              matlab.ui.container.Panel
        LoadTrack2Button         matlab.ui.control.Button
        LoadRecordingButton      matlab.ui.control.Button
        UIAxes_2                 matlab.ui.control.UIAxes
        RecordAudioPanel         matlab.ui.container.Panel
        Lamp                     matlab.ui.control.Lamp
        StartRecordingButton     matlab.ui.control.Button
        StopRecordingButton      matlab.ui.control.Button
        PlayRecordingButton      matlab.ui.control.Button
        ClearRecordingButton     matlab.ui.control.Button
        ControlsPanel_2          matlab.ui.container.Panel
        EditPanel_2              matlab.ui.container.Panel
        StartSpinner_2Label      matlab.ui.control.Label
        StartSpinner_2           matlab.ui.control.Spinner
        EndSpinner_2Label        matlab.ui.control.Label
        EndSpinner_2             matlab.ui.control.Spinner
        CutButton_2              matlab.ui.control.Button
        VolumeSlider_2Label      matlab.ui.control.Label
        VolumeSlider_2           matlab.ui.control.Slider
        PlayButton_2             matlab.ui.control.Button
        PauseButton_2            matlab.ui.control.Button
        ResumeButton_2           matlab.ui.control.Button
        StopButton_2             matlab.ui.control.Button
        Track1Panel              matlab.ui.container.Panel
        LoadTrack1Button         matlab.ui.control.Button
        UIAxes_1                 matlab.ui.control.UIAxes
        MergeAudioPanel          matlab.ui.container.Panel
        Insertaudio2atLabel      matlab.ui.control.Label
        Insertaudio2atSpinner    matlab.ui.control.Spinner
        MergeAudioButton         matlab.ui.control.Button
        ExportMergedAudioButton  matlab.ui.control.Button
        StopMergedAudioButton    matlab.ui.control.Button
        PauseMergedAudioButton   matlab.ui.control.Button
        PlayMergedAudioButton    matlab.ui.control.Button
        ResumeMergedAudioButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        y1, y1orig, fs1, uploaded1 = false, player1, y2, y2orig, fs2, uploaded2 = false, player2, y3, fs3, y3merged = false, player3, recfile = false, yrecorded, recorder, recording = false, playerrec;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadTrack1Button
        function LoadTrack1ButtonPushed(app, event)
            % Allow user to select WAVE or MP3 file.
            [file,path] = uigetfile({'*.wav';'*.mp3'}, 'Select Audio 1');
            if isequal(file,0)
                disp('Cancelled');
            else
                % Read in file
                selectedfile1 = fullfile(path,file);
                [app.y1, app.fs1] = audioread(selectedfile1);
                [app.y1orig, app.fs1] = audioread(selectedfile1);
                app.uploaded1 = true;
                
                % Convert length to minutes and plot on graph
                t = (0:1/app.fs1:(length(app.y1)-1)/app.fs1)/60;   
                plot(app.UIAxes_1,t,app.y1);
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % Check file 1 exists
            if isequal(app.uploaded1, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
                
            else
                % Initiate player
                app.player1 = audioplayer(app.y1, app.fs1);
                % start the playback
                play(app.player1);
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            % Check file 1 is being played
            if isequal(app.uploaded1, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
        
            elseif isplaying(app.player1)
                % Pause the playback
                pause(app.player1);
                
            else
                fig = app.UIFigure;
                uialert(fig,'No file playing.','Playback Error');
            end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            % Check file 1 exists
            if isequal(app.uploaded1, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
        
            else
                % Resume the playback
                resume(app.player1);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Check file 1 is being played or is paused
            if isequal(app.uploaded1, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
        
            elseif isplaying(app.player1)
                % Stop the playback
                stop(app.player1);
                
            else
                fig = app.UIFigure;
                uialert(fig,'No file playing.','Playback Error');
            end
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            if isequal(app.uploaded1, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
        
            elseif isplaying(app.player1)
                % stop the playback
                stop(app.player1);
            end
            
            % Get value of slider and multiply audio by value to change the
            % volume
            sliderValue1 = get(app.VolumeSlider, 'Value');
            app.y1 = app.y1orig*sliderValue1;
        end

        % Button pushed function: CutButton
        function CutButtonPushed(app, event)
            % Check file 1 exists
            if isequal(app.uploaded1, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
                
            else
                % Get value from both spinners and convert to seconds
                starttime = (app.StartSpinner.Value * 60);
                endtime = (app.EndSpinner.Value * 60);

                samples=[length(app.y1)-(starttime*app.fs1),length(app.y1)-(endtime*app.fs1)];
                app.y1 = app.y1(samples);
                
                % Replot on graph
                cla(app.UIAxes_1)
                % Convert length to minutes and plot on graph
                t = (0:1/app.fs1:(length(app.y1)-1)/app.fs1)/60;   
                plot(app.UIAxes_1,t,app.y1);
            end
        end

        % Button pushed function: LoadTrack2Button
        function LoadTrack2ButtonPushed(app, event)
            % Allow user to select WAVE or MP3 file.
            [file,path] = uigetfile({'*.wav';'*.mp3'}, 'Select Audio 2');
            if isequal(file,0)
                disp('Cancelled');
            else
                % Read in file
                selectedfile2 = fullfile(path,file);
                [app.y2, app.fs2] = audioread(selectedfile2);
                [app.y2orig, app.fs2] = audioread(selectedfile2);
                app.uploaded2 = true;
                
                % Convert length to minutes and plot on graph
                t = (0:1/app.fs2:(length(app.y2)-1)/app.fs2)/60;   
                plot(app.UIAxes_2,t,app.y2);
            end
        end

        % Button pushed function: LoadRecordingButton
        function LoadRecordingButtonPushed(app, event)
            if isequal (app.recfile, false)
                fig = app.UIFigure;
                uialert(fig,'No recorded file.','File Error');
            else
                % Read in file
                app.y2 = app.yrecorded;
                app.y2orig = app.yrecorded;
                app.uploaded2 = true;
                app.fs2 = 8000;
                
                % Convert length to minutes and plot on graph
                t = (0:1/app.fs2:(length(app.y2)-1)/app.fs2)/60;   
                plot(app.UIAxes_2,t,app.y2);
            end
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            % Check file 2 exists
            if isequal(app.uploaded2, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
                
            else
                % Initiate player
                app.player2 = audioplayer(app.y2, app.fs2);
                % start the playback
                play(app.player2);
            end
        end

        % Button pushed function: PauseButton_2
        function PauseButton_2Pushed(app, event)
            % Check file 2 is being played
            if isequal(app.uploaded2, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
        
            elseif isplaying(app.player2)
                % Pause the playback
                pause(app.player2);
                
            else
                fig = app.UIFigure;
                uialert(fig,'No file playing.','Playback Error');
            end
        end

        % Button pushed function: ResumeButton_2
        function ResumeButton_2Pushed(app, event)
            % Check file 2 exists
            if isequal(app.uploaded2, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
        
            else
                % Resume the playback
                resume(app.player2);
            end
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            % Check file 2 is being played or is paused
            if isequal(app.uploaded2, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
        
            elseif isplaying(app.player2)
                % Stop the playback
                stop(app.player2);
                
            else
                fig = app.UIFigure;
                uialert(fig,'No file playing.','Playback Error');
            end
        end

        % Value changed function: VolumeSlider_2
        function VolumeSlider_2ValueChanged(app, event)
            if isequal(app.uploaded2, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
        
            elseif isplaying(app.player2)
                % stop the playback
                stop(app.player2);
            end
            
            % Get value of slider and multiply audio by value to change the
            % volume
            sliderValue2 = get(app.VolumeSlider_2, 'Value');
            app.y2 = app.y2orig*sliderValue2;
        end

        % Button pushed function: CutButton_2
        function CutButton_2Pushed(app, event)
            % Check file 2 exists
            if isequal(app.uploaded2, false)
                fig = app.UIFigure;
                uialert(fig,'No file imported.','File Error');
                
            else
                % Get value from both spinners and convert to seconds
                starttime = (app.StartSpinner_2.Value * 60);
                endtime = (app.EndSpinner_2.Value * 60);
                
                startSample = starttime*app.fs2;
                endSample = endtime*app.fs2;
                app.y2 = app.y2(startSample:endSample);
            end
        end

        % Button pushed function: MergeAudioButton
        function MergeAudioButtonPushed(app, event)
            if isequal(app.uploaded1,false) || isequal(app.uploaded2,false)
                fig = app.UIFigure;
                uialert(fig,'Please select two audio files.','Invalid Audio Files');
            else
                % Get value from spinner
                timeVal = app.Insertaudio2atSpinner.Value;
            
                % Convert spinner value to seconds
                insertTime = timeVal*60;
            
                % Pad y2 with zeroes to insert at the specified time
                y2padded = [zeros(app.fs2*insertTime,1); app.y2];    
                
                % Determine which file is shorter in order to pad file to
                % be equal
                y1len = length(app.y1);
                y2len = length(y2padded);
                
                if isequal(y1len, y2len)
                    y1long = app.y1;
                    y2long = y2padded;
                    
                elseif y1len < y2len
                    y1long = [app.y1;zeros(y2len-y1len,1)];
                    y2long = y2padded;
                    
                else
                    y1long = app.y1;
                    y2long = [y2padded;zeros(y1len-y2len,1)];
                end

                app.y3 = (y1long+y2long)/2;
                app.y3merged = true;
                
                if (app.fs1 > app.fs2)
                    app.fs3 = app.fs1;
                else 
                    app.fs3 = app.fs2;
                end
                
%                 maxFs = max(app.fs1, app.fs2);
%                 rs1 = resample(app.y1, maxFs, app.fs1);
%                 rs2 = resample(y2padded, maxFs, app.fs2);
%                 maxlength = max(size(rs1,1), size(rs2,1));
%                 rs1(end+1:maxlength,:) = 0;
%                 rs2(end+1:maxlength,:) = 0;
%                 app.y3 = [rs1, rs2];
            end
        end

        % Button pushed function: ExportMergedAudioButton
        function ExportMergedAudioButtonPushed(app, event)
            % Check merged file exists
            if isequal(app.y3merged, false)
                fig = app.UIFigure;
                uialert(fig,'No merged file.','File Error');
                
            else
                [app.y3] = uiputfile({'*.mp3';'*.wav'},'Save Merged Audio');
            end
        end

        % Button pushed function: PlayMergedAudioButton
        function PlayMergedAudioButtonPushed(app, event)
            % Check merged file exists
            if isequal(app.y3merged, false)
                fig = app.UIFigure;
                uialert(fig,'No merged file.','File Error');
                
            else
                % Initiate player
                app.player3 = audioplayer(app.y3, app.fs3);
                % start the playback
                play(app.player3);
            end
        end

        % Button pushed function: PauseMergedAudioButton
        function PauseMergedAudioButtonPushed(app, event)
            % Check merged file is being played
            if isequal(app.y3merged, false)
                fig = app.UIFigure;
                uialert(fig,'No merged file.','File Error');
        
            elseif isplaying(app.player3)
                % pause the playback
                pause(app.player3);
                
            else
                fig = app.UIFigure;
                uialert(fig,'No file playing.','Playback Error');
            end
        end

        % Button pushed function: ResumeMergedAudioButton
        function ResumeMergedAudioButtonPushed(app, event)
            % Check merged file exists
            if isequal(app.y3merged, false)
                fig = app.UIFigure;
                uialert(fig,'No merged file.','File Error');
        
            else
                % Resume the playback
                resume(app.player3);
            end
        end

        % Button pushed function: StopMergedAudioButton
        function StopMergedAudioButtonPushed(app, event)
            % Check merged file is being played or is paused
            if isequal(app.y3merged, false)
                fig = app.UIFigure;
                uialert(fig,'No merged file.','File Error');
        
            elseif isplaying(app.player3)
                % stop the playback
                stop(app.player3);
            else
                fig = app.UIFigure;
                uialert(fig,'No file playing.','Playback Error');
            end
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            if isequal(app.recording, false) 
                app.recorder = audiorecorder;
                record(app.recorder);
                app.recording = true;
                app.Lamp.Color = 'red';
            else
                fig = app.UIFigure;
                uialert(fig,'Already recording.','Recording Error');
            end
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            if isequal(app.recording, true) 
                stop(app.recorder)
                app.recording = false;
                app.Lamp.Color = '#595959';
                app.yrecorded = getaudiodata(app.recorder);
                app.recfile = true;
            else
                fig = app.UIFigure;
                uialert(fig,'Not recording.','Recording Error');
            end
        end

        % Button pushed function: PlayRecordingButton
        function PlayRecordingButtonPushed(app, event)
            if isequal(app.recfile, true) 
                % Initiate player
                app.playerrec = audioplayer(app.yrecorded, 8000);
                % start the playback
                play(app.playerrec);
            else
                fig = app.UIFigure;
                uialert(fig,'No recording.','Recording Error');
            end
        end

        % Button pushed function: ClearRecordingButton
        function ClearRecordingButtonPushed(app, event)
            if isequal(app.recfile, true) 
                app.yrecorded = []; 
                app.recfile = false;
            else
                fig = app.UIFigure;
                uialert(fig,'No recording.','Recording Error');
            end
        end

        % Menu selected function: HowToUseMenu
        function HowToUseMenuSelected(app, event)
            fig = app.UIFigure;
            uialert(fig,{'Use import to select the two files you want to use.';'Once files are imported, use playback controls to hear the files, adjust volume with the sliders, and cut files with the edit controls. Make sure to adjust volume before cutting.';'Audio can be recorded directly into the software and used as audio 2.';'Once editing the files is complete, use the merge controls to combine the two files into one at the specified time.';'Use export to download the merged audio.'},'How to Use','Icon','info');
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            fig = app.UIFigure;
            uialert(fig,{'Software created by Millie Allen, Software Engineering Student at The University of Northampton';'Version 1.0';'Created 2021, Matlab R2020b'},'How to Use','Icon','info');
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            % Make current instance of app invisible
            app.UIFigure.Visible = 'off';
            soundannotator();
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SoundAnnotatorMenu = uimenu(app.UIFigure);
            app.SoundAnnotatorMenu.Enable = 'off';
            app.SoundAnnotatorMenu.Text = 'Sound Annotator';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.HowToUseMenu = uimenu(app.HelpMenu);
            app.HowToUseMenu.MenuSelectedFcn = createCallbackFcn(app, @HowToUseMenuSelected, true);
            app.HowToUseMenu.Text = 'How To Use';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ResetMenu = uimenu(app.UIFigure);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.VolumeSliderLabel = uilabel(app.ControlsPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.ControlsPanel);
            app.VolumeSlider.Limits = [0.1 2];
            app.VolumeSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.MinorTicks = [0.25 0.75 1.25 1.75];
            app.VolumeSlider.Value = 1;
            app.EditPanel = uipanel(app.ControlsPanel);
            app.EditPanel.Title = 'Edit';
            app.StartSpinnerLabel = uilabel(app.EditPanel);
            app.StartSpinnerLabel.Text = 'Start';
            app.StartSpinner = uispinner(app.EditPanel);
            app.StartSpinner.Step = 0.01;
            app.StartSpinner.Limits = [0 Inf];
            app.EndSpinnerLabel = uilabel(app.EditPanel);
            app.EndSpinnerLabel.Text = 'End';
            app.EndSpinner = uispinner(app.EditPanel);
            app.EndSpinner.Step = 0.01;
            app.EndSpinner.Limits = [0 Inf];
            app.CutButton = uibutton(app.EditPanel, 'push');
            app.CutButton.ButtonPushedFcn = createCallbackFcn(app, @CutButtonPushed, true);
            app.CutButton.Text = 'Cut';
            app.PlayButton = uibutton(app.ControlsPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.ControlsPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.ControlsPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Track2Panel = uipanel(app.UIFigure);
            app.Track2Panel.Title = 'Track 2';
            app.LoadTrack2Button = uibutton(app.Track2Panel, 'push');
            app.LoadTrack2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadTrack2ButtonPushed, true);
            app.LoadTrack2Button.Text = 'Load Track 2';
            app.LoadRecordingButton = uibutton(app.Track2Panel, 'push');
            app.LoadRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRecordingButtonPushed, true);
            app.LoadRecordingButton.Text = 'Load Recording';
            app.UIAxes_2 = uiaxes(app.Track2Panel);
            title(app.UIAxes_2, 'Track 2')
            xlabel(app.UIAxes_2, 'Time (Minutes)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 120];
            app.UIAxes_2.YLim = [-1 1];
            app.UIAxes_2.XTick = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.UIAxes_2.YTick = [-1 -0.5 0 0.5 1];
            app.UIAxes_2.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.RecordAudioPanel = uipanel(app.UIFigure);
            app.RecordAudioPanel.Title = 'Record Audio';
            app.Lamp = uilamp(app.RecordAudioPanel);
            app.StartRecordingButton = uibutton(app.RecordAudioPanel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.StopRecordingButton = uibutton(app.RecordAudioPanel, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Text = 'Stop Recording';
            app.PlayRecordingButton = uibutton(app.RecordAudioPanel, 'push');
            app.PlayRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordingButtonPushed, true);
            app.PlayRecordingButton.Text = 'Play Recording';
            app.ClearRecordingButton = uibutton(app.RecordAudioPanel, 'push');
            app.ClearRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @ClearRecordingButtonPushed, true);
            app.ClearRecordingButton.Text = 'Clear Recording';
            app.ControlsPanel_2 = uipanel(app.UIFigure);
            app.ControlsPanel_2.Title = 'Controls';
            app.EditPanel_2 = uipanel(app.ControlsPanel_2);
            app.EditPanel_2.Title = 'Edit';
            app.StartSpinner_2Label = uilabel(app.EditPanel_2);
            app.StartSpinner_2Label.Text = 'Start';
            app.StartSpinner_2 = uispinner(app.EditPanel_2);
            app.StartSpinner_2.Step = 0.01;
            app.EndSpinner_2Label = uilabel(app.EditPanel_2);
            app.EndSpinner_2Label.Text = 'End';
            app.EndSpinner_2 = uispinner(app.EditPanel_2);
            app.EndSpinner_2.Step = 0.01;
            app.CutButton_2 = uibutton(app.EditPanel_2, 'push');
            app.CutButton_2.ButtonPushedFcn = createCallbackFcn(app, @CutButton_2Pushed, true);
            app.CutButton_2.Text = 'Cut';
            app.VolumeSlider_2Label = uilabel(app.ControlsPanel_2);
            app.VolumeSlider_2Label.Text = 'Volume';
            app.VolumeSlider_2 = uislider(app.ControlsPanel_2);
            app.VolumeSlider_2.Limits = [0.1 2];
            app.VolumeSlider_2.MajorTicks = [0 0.5 1 1.5 2];
            app.VolumeSlider_2.ValueChangedFcn = createCallbackFcn(app, @VolumeSlider_2ValueChanged, true);
            app.VolumeSlider_2.MinorTicks = [0.25 0.75 1.25 1.75];
            app.VolumeSlider_2.Value = 1;
            app.PlayButton_2 = uibutton(app.ControlsPanel_2, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.PauseButton_2 = uibutton(app.ControlsPanel_2, 'push');
            app.PauseButton_2.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_2Pushed, true);
            app.PauseButton_2.Text = 'Pause';
            app.ResumeButton_2 = uibutton(app.ControlsPanel_2, 'push');
            app.ResumeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResumeButton_2Pushed, true);
            app.ResumeButton_2.Text = 'Resume';
            app.StopButton_2 = uibutton(app.ControlsPanel_2, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.Track1Panel = uipanel(app.UIFigure);
            app.Track1Panel.Title = 'Track 1';
            app.LoadTrack1Button = uibutton(app.Track1Panel, 'push');
            app.LoadTrack1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadTrack1ButtonPushed, true);
            app.LoadTrack1Button.Text = 'Load Track 1';
            app.UIAxes_1 = uiaxes(app.Track1Panel);
            title(app.UIAxes_1, 'Track 1')
            xlabel(app.UIAxes_1, 'Time (Minutes)')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_1.XLim = [0 120];
            app.UIAxes_1.YLim = [-1 1];
            app.UIAxes_1.XTick = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.UIAxes_1.YTick = [-1 -0.5 0 0.5 1];
            app.UIAxes_1.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.MergeAudioPanel = uipanel(app.UIFigure);
            app.MergeAudioPanel.Title = 'Merge Audio';
            app.Insertaudio2atLabel = uilabel(app.MergeAudioPanel);
            app.Insertaudio2atLabel.Text = 'Insert audio 2 at:';
            app.Insertaudio2atSpinner = uispinner(app.MergeAudioPanel);
            app.Insertaudio2atSpinner.Step = 0.01;
            app.Insertaudio2atSpinner.Limits = [0 120];
            app.MergeAudioButton = uibutton(app.MergeAudioPanel, 'push');
            app.MergeAudioButton.ButtonPushedFcn = createCallbackFcn(app, @MergeAudioButtonPushed, true);
            app.MergeAudioButton.Text = 'Merge Audio';
            app.ExportMergedAudioButton = uibutton(app.MergeAudioPanel, 'push');
            app.ExportMergedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMergedAudioButtonPushed, true);
            app.ExportMergedAudioButton.Text = 'Export Merged Audio';
            app.StopMergedAudioButton = uibutton(app.MergeAudioPanel, 'push');
            app.StopMergedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopMergedAudioButtonPushed, true);
            app.StopMergedAudioButton.Text = 'Stop Merged Audio';
            app.PauseMergedAudioButton = uibutton(app.MergeAudioPanel, 'push');
            app.PauseMergedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PauseMergedAudioButtonPushed, true);
            app.PauseMergedAudioButton.Text = 'Pause Merged Audio';
            app.PlayMergedAudioButton = uibutton(app.MergeAudioPanel, 'push');
            app.PlayMergedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayMergedAudioButtonPushed, true);
            app.PlayMergedAudioButton.Text = 'Play Merged Audio';
            app.ResumeMergedAudioButton = uibutton(app.MergeAudioPanel, 'push');
            app.ResumeMergedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeMergedAudioButtonPushed, true);
            app.ResumeMergedAudioButton.Text = 'Resume Merged Audio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = soundannotator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end